classdef RSFaultZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RSFaultZUIFigure               matlab.ui.Figure
        EffectiveNormalStressButtonGroup  matlab.ui.container.ButtonGroup
        PorePressureRatioEditField     matlab.ui.control.NumericEditField
        NormalStressMPaEditField       matlab.ui.control.NumericEditField
        PorePressureRatioButton        matlab.ui.control.RadioButton
        ConstantMPaButton              matlab.ui.control.RadioButton
        Label                          matlab.ui.control.Label
        ExecutionTimesLabel            matlab.ui.control.Label
        TimeLabel                      matlab.ui.control.Label
        SimulationControlsPanel        matlab.ui.container.Panel
        RunTimeyrEditField             matlab.ui.control.NumericEditField
        RunTimeyrEditFieldLabel        matlab.ui.control.Label
        PlotsOnCheckBox                matlab.ui.control.CheckBox
        StateLawDropDown               matlab.ui.control.DropDown
        StateLawDropDownLabel          matlab.ui.control.Label
        RestartButton                  matlab.ui.control.Button
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        GridControlsPanel              matlab.ui.container.Panel
        FourierSpaceCheckBox           matlab.ui.control.CheckBox
        ImportEditField                matlab.ui.control.EditField
        ImportButton                   matlab.ui.control.Button
        LoadingRatemsEditField         matlab.ui.control.NumericEditField
        LoadingRatemsEditFieldLabel    matlab.ui.control.Label
        LbdxiEditField                 matlab.ui.control.NumericEditField
        LbdxiEditFieldLabel            matlab.ui.control.Label
        dximEditField                  matlab.ui.control.NumericEditField
        dximEditFieldLabel             matlab.ui.control.Label
        LbkmLabel                      matlab.ui.control.Label
        hkmLabel                       matlab.ui.control.Label
        GridPointsEditField            matlab.ui.control.NumericEditField
        GridPointsEditFieldLabel       matlab.ui.control.Label
        SetPropertiesButton            matlab.ui.control.Button
        FrictionParametersPanel        matlab.ui.container.Panel
        mu0EditField                   matlab.ui.control.NumericEditField
        mu0EditFieldLabel              matlab.ui.control.Label
        v0EditField                    matlab.ui.control.NumericEditField
        v0msLabel                      matlab.ui.control.Label
        dcmEditField                   matlab.ui.control.NumericEditField
        dcmLabel                       matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        ElasticParametersPanel         matlab.ui.container.Panel
        vsmsEditField                  matlab.ui.control.NumericEditField
        SWaveSpeedmsEditFieldLabel     matlab.ui.control.Label
        PoissonRatioEditField          matlab.ui.control.NumericEditField
        PoissonRatioEditFieldLabel     matlab.ui.control.Label
        ShearModulusGPaEditField       matlab.ui.control.NumericEditField
        ShearModulusGPaEditFieldLabel  matlab.ui.control.Label
        GeometryPanel                  matlab.ui.container.Panel
        GeometryDropDown               matlab.ui.control.DropDown
        GeometryDropDownLabel          matlab.ui.control.Label
        PlotGeometryButton             matlab.ui.control.Button
        BurialDepthkmEditField         matlab.ui.control.NumericEditField
        BurialDepthkmEditFieldLabel    matlab.ui.control.Label
        FaultLengthkmEditField         matlab.ui.control.NumericEditField
        FaultLengthkmEditFieldLabel    matlab.ui.control.Label
        DipAngleEditField              matlab.ui.control.NumericEditField
        DipAngleEditFieldLabel         matlab.ui.control.Label
        SurfaceSlopeEditField          matlab.ui.control.NumericEditField
        SurfaceSlopeEditFieldLabel     matlab.ui.control.Label
        VelAxes                        matlab.ui.control.UIAxes
        GeoAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p       % structure containing all properties and geometric information.
        STOP    % flag for stopping a simulation.
        Out     % stucture containing output from a completed simulation.
    end
    
    methods (Access = private)
        
        function DippingFaultSetUpUI(app)
        %%% Get parameter values from fields. This function is called by the "Set Properties" button.
            
        %%% Run time of the simulation.            
            RunTime = app.RunTimeyrEditField.Value;
            time = [0 RunTime];
            
        %%% Frictional parameters.
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            d_c = app.dcmEditField.Value;
            v_ref = app.v0EditField.Value;
            mu_0 = app.mu0EditField.Value;

            v_plate = app.LoadingRatemsEditField.Value;

        %%% Elastic parameters.
            v_shear = app.vsmsEditField.Value;

        %%% Effective Stress.
            EffStress_0 = app.NormalStressMPaEditField.Value;
%             selectedButton = app.EffectiveNormalStressButtonGroup.SelectedObject;
%             if iscell(selectedButton.Text) == 1 % Pore pressure ratio
%                 rho_s = 2650;
%                 rho_w = 1000;               
%             else % Constant
%                 EffStress_0 = app.NormalStressMPaEditField.Value;
%             end
            
            
            switch(app.GeometryDropDown.Value)
                case 'Full Space'
                    Poisson_1 = app.PoissonRatioEditField.Value;
                    ShearMod_1 = 1e3*app.ShearModulusGPaEditField.Value;
                    ShearMod_2 = ShearMod_1;
                    Poisson_2 = Poisson_1;
                    
                    Beta = -(ShearMod_2*(1 - 2*Poisson_1) - ShearMod_1*(1 - 2*Poisson_2))...
                        ./(2*(ShearMod_2*(1 - Poisson_1) + ShearMod_1*(1 - Poisson_2)));

                    M = (2*ShearMod_1*ShearMod_2/(1 - Beta^2))/((ShearMod_2*(1 - Poisson_1)...
                        + ShearMod_1*(1 - Poisson_2)));
                    
                %%% Bimaterial parameters from Heimisson.
                    % M = ShearMod_1/(1 - Poisson_1);
                    % B = 0.6;
                    % Beta = 2*B*(1 + Poisson_1)/3;

                %%% radiation damping [MPa s / m]
                    eta = ShearMod_1/(2*v_shear);
                    
                %%% Length scales.    
                    H_s = pi*M*d_c/(EffStress_0*(b-a)); 
                    L_b = d_c*M/(b*EffStress_0);
                
                %%% Set up the numerical grid.
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                    dxi = app.dximEditField.Value;

                %%% Fourier space option.
                    if app.FourierSpaceCheckBox.Value
                        N_dxi = round(FaultLength/dxi);
                        n2 = nextpow2(N_dxi);
                        if 2^n2 < 0.9*N_dxi
                            N = 2^(n2 + 1);
                        else
                            N = 2^n2;
                        end
                        N_tot = 7*N;
                        dxi = FaultLength/(N - 1);
                        TotalLength = N_tot*dxi;                    
                        Xi = (0 : dxi : FaultLength)' - FaultLength/2;
                        N_pad = (N_tot - N)/2;
                        app.dximEditField.Value = dxi;
                    %%% Computate the wave number.
                        WaveNumber = fftshift(2*pi.*abs((-N_tot/2:N_tot/2-1)')./(TotalLength-dxi));
                        WaveNumber2 = 2*pi.*((-N_tot/2:N_tot/2-1)')./(TotalLength-dxi);
                                
                    else
                    %%% Piece-wise constant discretization.
                        N = app.GridPointsEditField.Value;
                        Xi = (dxi/2: dxi: N*dxi - dxi/2)' - FaultLength/2;

                    %%% Compute the elastic stress change matrices for the defined grid.                    
                        K_s = hodlr('handle', @(i,j) FullSpaceStress_HODLR(i, j, Xi', 1), N, N);
                        % K_s = hodlr('handle', @(i,j) FullSpaceREPStress_HODLR(i, j, Xi', 1), N, N);
                        % K_n = hodlr('handle', @(i,j) FullSpaceStress_HODLR(i, j, Xi', 0), N, N);
                        K_n = 0;
                    end

                case {'Thrust Fault', 'Normal Fault'}
                    Poisson = app.PoissonRatioEditField.Value;
                    ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
                    eta = ShearMod/(2*v_shear);             % radiation damping [MPa s / m]
                    
                %%% Length scales.    
                    H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a));
                    L_b = d_c*ShearMod/(b*EffStress_0*(1 - Poisson));
                    
                %%% Set up grid, xi is the along-fault coordinate.
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                    dxi = app.dximEditField.Value;
                    N = app.GridPointsEditField.Value;
                    Xi = 0*dxi + (dxi/2: dxi: N*dxi - dxi/2)';
    
                %%% Wedge geometry.
                    alpha = app.SurfaceSlopeEditField.Value;
                    beta = app.DipAngleEditField.Value;
                %%% Burial depth - depth of updip edge.
                    BurialDepth = 1e3*app.BurialDepthkmEditField.Value;
                %%% Zed is the fault's depth below the upper surface.
                    Zed = BurialDepth + Xi*(sin(pi*beta/180)...
                        + cos(pi*beta/180)*tan(pi*alpha/180));
                %%% Horizontal location.
                    X = Xi*cos(pi*beta/180);
                    
                %%% Compute the elastic stress change matrices for the defined grid.                    
                    K_s = hodlr('handle', @(i,j) WedgeStress_HODLR(i, j, Xi',...
                        beta, alpha, BurialDepth, 1), N, N);
                    K_n = hodlr('handle', @(i,j) WedgeStress_HODLR(i, j, Xi',...
                        beta, alpha, BurialDepth, 0), N, N);

                case 'Strike Slip'
                    ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
                    eta = ShearMod/(2*v_shear);             % radiation damping [MPa s / m]
                    
                %%% Length scales.    
                    H_s = pi*ShearMod*d_c/(EffStress_0*(b-a));
                    L_b = d_c*ShearMod/(b*EffStress_0);
                    
                %%% Set up grid, xi is the along-fault coordinate.
                    FaultLength = 1e3*app.FaultLengthkmEditField.Value;
                    dxi = app.dximEditField.Value;
                    N = app.GridPointsEditField.Value;
                    Xi = 0*dxi + (dxi/2: dxi: N*dxi - dxi/2)';
    
                %%% Wedge geometry.
                    alpha = 0;
                    % alpha = app.SurfaceSlopeEditField.Value;
                    beta = app.DipAngleEditField.Value;
                %%% Burial depth - depth of updip edge.
                    BurialDepth = 1e3*app.BurialDepthkmEditField.Value;
                %%% Zed is the fault's depth below the upper surface.
                    Zed = BurialDepth + Xi*(sin(pi*beta/180) + cos(pi*beta/180)*tan(pi*alpha/180));
                %%% Horizontal location. Absolute value accounts for beta >
                %%% 90.
                    X = Xi*abs(cos(pi*beta/180));
                    
                %%% Compute the elastic stress change matrices for the defined grid.                    
                    K_s = hodlr('handle', @(i,j) StrikeSlipStress_HODLR(i, j, Xi', beta), N, N);
                    K_n = 0;
            end                           

        %%% External loading rate.
            % spy = 365.25*24*3600;
            % Loading = 1e-1/spy;
            Loading = 0;
            % Loading = real((v_plate/pi)*(pi/2 + asin((FaultLength/2 ...
            %     - (Xi - Xi(1)))/(FaultLength/2)))) + 1e-6*v_plate;
            
        %%% Initial conditions.

        %%% Initial slip velocity.
        %%% At plate rate steady state.
            % v_0 = 1*v_plate*ones(size(Xi));
        %%% Uniform, slightly below steady state.
            % v_0 = .99*v_plate*ones(size(Xi));
        %%% p/m 0.01 random variation.
            v_0 = v_plate*(((rand(N,1) + 0.5) - 1)/50 + 1);
        %%% Logarithmically random; probaly should delete this.
            % v_0 = v_plate*10.^((rand(N,1) - 0.5)/.5);

        %%% Initial state.
        %%% At steady state.
            state_0 = (d_c./v_plate).*ones(size(Xi));
        %%% p/m 0.05 random variation.
            % state_0 = (d_c/v_plate)*(((rand(N,1) + 0.5) - 1)/10 + 1);
        %%% p/m 0.01 random variation.
            % state_0 = (d_c/v_plate)*(((rand(N,1) + 0.5) - 1)/50 + 1);
            
        %%% Initial normal stress.
            sigma_0 = EffStress_0.*ones(size(Xi));

        %%% Initial shear stress.
            tau_0 = mu_0.*sigma_0;
                    
            Vars_0 = [v_0; tau_0; state_0; sigma_0];
            
        %%% Parameters structure containing all necessary information to run a simulation.
        %%% Friction parameters.
            app.p.Friction = struct('a', a, 'b', b, 'd_c', d_c, 'v_ref', v_ref, 'mu_ref', mu_0,...
                'H_star', H_s, 'L_b', L_b);
        %%% Options.
            plots = true;
            app.p.Options = struct('StateLaw', app.StateLawDropDown.Value, 'Geometry',...
                app.GeometryDropDown.Value, 'Restart', 0, 'Plots', plots, 'Import', 0,...
                'FourierSpace', 0, 'DataManagement', 'ode');
        
        %%% Different parameters for the different geometries.
            switch(app.GeometryDropDown.Value)
                case 'Full Space'
                %%% Geometrical and numerical parameters.
                    if app.FourierSpaceCheckBox.Value
                        app.p.Geometry = struct('FaultLength', FaultLength, 'FaultCoordinates', Xi,...
                            'GridPoints', N, 'GridSpacing', dxi, 'Padding', N_pad, 'WaveNumber',...
                            [WaveNumber, WaveNumber2], 'Runtime', time, 'Loading', Loading,...
                            'InitConditions', Vars_0);
                    else
                        app.p.Geometry = struct('FaultLength', FaultLength, 'FaultCoordinates', Xi,...
                            'GridPoints', N, 'GridSpacing', dxi, 'Runtime', time, 'Loading', Loading,...
                            'InitConditions', Vars_0, 'ShearKernel',  K_s, 'NormalKernel', K_n);
                    end
                %%% Material parameters.
                    app.p.Material = struct('ShearModulus', ShearMod_1,  'PoissonRatio', Poisson_1,...
                        'BackgroundStress', EffStress_0, 'PlateRate', v_plate, 'ShearWaveSpeed',...
                        v_shear, 'RadiationDamping', eta);
                %%% Fourier space option.
                    app.p.Options.FourierSpace = app.FourierSpaceCheckBox.Value;
                    
                case {'Thrust Fault', 'Normal Fault'}
                %%% Geometrical and numerical parameters.    
                    app.p.Geometry = struct('SurfaceSlope', alpha, 'FaultDip', beta, 'BurialDepth',...
                        BurialDepth, 'FaultLength', FaultLength, 'FaultCoordinates', Xi, 'GridPoints',...
                        N, 'GridSpacing', dxi, 'Runtime', time, 'Loading', Loading, 'InitConditions',...
                        Vars_0, 'ShearKernel', K_s, 'NormalKernel', K_n, 'Depth', Zed, 'Distance', X);            
                %%% Material parameters.
                    app.p.Material = struct('ShearModulus', ShearMod, 'PoissonRatio', Poisson,...
                        'BackgroundStress', EffStress_0, 'PlateRate', v_plate, 'ShearWaveSpeed',...
                        v_shear, 'RadiationDamping', eta);

                case 'Strike Slip'
                %%% Geometrical and numerical parameters.    
                    app.p.Geometry = struct('SurfaceSlope', alpha, 'FaultDip', beta, 'BurialDepth',...
                        BurialDepth, 'FaultLength', FaultLength, 'FaultCoordinates', Xi, 'GridPoints',...
                        N, 'GridSpacing', dxi, 'Runtime', time, 'Loading', Loading, 'InitConditions',...
                        Vars_0, 'ShearKernel', K_s, 'Depth', Zed, 'Distance', X);            
                %%% Material parameters.
                    app.p.Material = struct('ShearModulus', ShearMod, 'BackgroundStress', EffStress_0,...
                        'PlateRate', v_plate, 'ShearWaveSpeed', v_shear, 'RadiationDamping', eta);
            end
            
        end % DippingFaultSetUpUI
        
        function GetParameters(app)
        %%% This function gets parameter values from numeric edit fields. It is called by the Start 
        %%% button. The start button should be deactivated if a grid doesn't exist, in which case p
        %%% will not exist as well. Therefore, this fuction just updates parameters/values in p that 
        %%% don't require a new grid.

        %%% Since the initial conditions depend on the grid, these get set whenever the grid i
        %%% is generated.
            
        %%% Run time of the simulation.            
            RunTime = app.RunTimeyrEditField.Value;
            time = [0 RunTime];

        %%% Update values in the parameters structure that do not require a new grid.
            if app.p.Options.Import == 0

                a = app.aEditField.Value;
                v_ref = app.v0EditField.Value;
                mu_0 = app.mu0EditField.Value;

                b = app.bEditField.Value;
                d_c = app.dcmEditField.Value;
                EffStress_0 = app.NormalStressMPaEditField.Value;            
                ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
                Poisson = app.PoissonRatioEditField.Value;
                v_shear = app.vsmsEditField.Value;

                H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a));
                eta = ShearMod/(2*v_shear);

                app.p.Friction.a = a;
                app.p.Friction.mu_ref = mu_0;
                app.p.Friction.v_ref = v_ref;
                app.p.Friction.H_star = H_s;
                app.p.Material.ShearWaveSpeed = v_shear;
                app.p.Material.RadiationDamping = eta;

            end

                          
        %%% Loading rate.
        %%% v_plate shouldn't be here? because it sets the initial conditions.
            v_plate = app.LoadingRatemsEditField.Value;
            % Loading = 0;

        %%% Update the parameters structure.
            app.p.Geometry.Runtime = time;
            % app.p.Geometry.Loading = Loading;
            
        %%% Material parameters.
            app.p.Material.PlateRate = v_plate;                   
            
        %%% Options.
            app.p.Options.Geometry = app.GeometryDropDown.Value;
            app.p.Options.StateLaw = app.StateLawDropDown.Value;
            
        end % GetParameters


        function Out = RunFaultSimUI(app)
        %%% This function sets up and runs the ODE solver.

        %%% Geometrical and numerical parameters.
            N = app.p.Geometry.GridPoints;
            spy = 24*365.25*3600;
            time = spy*app.p.Geometry.Runtime;            
            Loading = app.p.Geometry.Loading;
            
        %%% Material parameters.
            v_plate = app.p.Material.PlateRate;
            eta = app.p.Material.RadiationDamping;                     
            
        %%% Friction parameters.
            a = app.p.Friction.a;
            b = app.p.Friction.b;
            d_c = app.p.Friction.d_c;
            mu_ref = app.p.Friction.mu_ref;
            v_ref = app.p.Friction.v_ref;

        %%% Initial conditions.
            if app.p.Options.Restart == 0
                Vars_0 = app.p.Geometry.InitConditions;
            else
                Vars_0 = app.Out.Final;
            end

        %%% Options.
            StateCase = app.p.Options.StateLaw;
            GeoCase = app.p.Options.Geometry;
            switch(app.p.Options.DataManagement)
                case 'ode'
                    options = odeset('OutputFcn', @PlotVel_ode, 'Events', @Stop, 'Refine', 1,...
                        'RelTol', 1e-4, 'AbsTol', 1e-6);
                case 'dat'
                    options = odeset('OutputFcn', @PlotVel_dat, 'Events', @Stop, 'Refine', 1,...
                        'RelTol', 1e-4, 'AbsTol', 1e-6);
            end

            switch(GeoCase)
                case 'Full Space'
                    X = app.p.Geometry.FaultCoordinates;
                    Z = nan(size(X));
                    ShearMod = app.p.Material.ShearModulus;
                    Poisson = app.p.Material.PoissonRatio;
                    M = ShearMod/(1 - Poisson);                    
                    Beta = 0;
                    % M = app.p.Material.ElasticModulus;
                    % Beta = app.p.Material.BiMatContrast;

                    if app.p.Options.FourierSpace
                        WaveNumber = app.p.Geometry.WaveNumber(:,1);
                        WaveNumber2 = app.p.Geometry.WaveNumber(:,2);
                        N_pad = app.p.Geometry.Padding;
                        % Loading = 1e-8;
                        % ode89(@FullSpaceFault, time, Vars_0, options, X, N);
                        [Time, Vars] = ode89(@FullSpaceFault, time, Vars_0, options, X, Z, N);
                    else
                        K_s = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.ShearKernel;
                        % K_n = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.NormalKernel;
                        K_n = 0;
                        [Time, Vars] = ode89(@DippingFault, time, Vars_0, options, X, Z, N);
                    end

                case 'Thrust Fault' 
                    X = app.p.Geometry.Distance;
                    Z = app.p.Geometry.Depth;
                    ShearMod = app.p.Material.ShearModulus;
                    Poisson = app.p.Material.PoissonRatio;
                    K_s = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.ShearKernel;
                    K_n = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.NormalKernel;
                    switch(app.p.Options.DataManagement)
                        case 'ode'
                            [Time, Vars] = ode89(@DippingFault, time, Vars_0, options, X, Z, N);
                        case 'dat'
                            ode89(@DippingFault, time, Vars_0, options, X, Z, N);
                    end

                case 'Normal Fault'
                    X = app.p.Geometry.Distance;
                    Z = app.p.Geometry.Depth;
                    ShearMod = app.p.Material.ShearModulus;
                    Poisson = app.p.Material.PoissonRatio;
                    K_s = (ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.ShearKernel;
                    K_n = -(ShearMod/(2*pi*(1 - Poisson)))*app.p.Geometry.NormalKernel;
                    % ode89(@DippingFault, time, Vars_0, options, X, N);
                    [Time, Vars] = ode89(@DippingFault, time, Vars_0, options, X, Z, N);

                case 'Strike Slip'
                    X = app.p.Geometry.Distance;
                    Z = app.p.Geometry.Depth;
                    ShearMod = app.p.Material.ShearModulus;
                    K_s = (ShearMod/(2*pi))*app.p.Geometry.ShearKernel;
                    % K_n = (ShearMod/(2*pi))*app.p.Geometry.NormalKernel;
                    K_n = 0;
                    % ode89(@DippingFault, time, Vars_0, options, X, Z, N);
                    [Time, Vars] = ode89(@DippingFault, time, Vars_0, options, X, Z, N);
            end                                    

            switch(app.p.Options.DataManagement)
                case 'ode'
                %%% Collect simulation output into a single structure, then clear Vars from
                %%% memory.
                    Out = struct('Time', Time, 'Vel', Vars(:,1:N), 'ShearStress',...
                        Vars(:, N+1:2*N), 'State', Vars(:, 2*N+1:3*N), 'NormalStress',...
                        Vars(:, 3*N+1:4*N));
                    clear Vars
                    
                %%% Calculate slip.
                    Slip = [zeros(1, N); cumsum(repmat(diff(Out.Time),1,N).*Out.Vel(2:end,:))];
                    Out.Slip = Slip;
                    
                %%% Final conditions.
                    Out.Final = [Out.Vel(end,:)'; Out.ShearStress(end,:)'; Out.State(end,:)';...
                        Out.NormalStress(end,:)'];

                case 'dat'
                %%% For dat data management, all of the simulation output is stored in
                %%% .dat files in the working directory. These files may be large, and
                %%% attempting to load them could cause MATLAB to crash. IT MAY BE
                %%% POSSIBLE TO LOAD THE FINAL VARS FOR RESTARTING.
                    Out = [];
            end
            
%%%----------------------------------------------------------------------------------------------%%%
%%% Governing equaitions for a full-space fault.
            function dVarsdt = FullSpaceFault(t, Vars, X, Z, N)
                vel = Vars(1:N);
                % tau = Vars(N+1:2*N);
                state = Vars(2*N+1:3*N);
                sigma = Vars(3*N+1:4*N);
            
            %%% State evolution.
                switch(StateCase)
                    case 'Aging'
                        dstatedt = 1 - vel.*state./d_c;
                    case 'Slip'
                        dstatedt = -(vel.*state./d_c).*log(vel.*state./d_c);
                end
            %%% Shear stress
                Vel2 = [v_plate*ones(N_pad,1); vel; v_plate*ones(N_pad,1)];
                % Vel2 = [zeros(N_pad,1); vel - v_plate; zeros(N_pad,1)];
                Ve2_fft = fft(Vel2);
                Stress_fft = WaveNumber.*Ve2_fft;
                Stress_fft(1) = -v_plate;
                ElasticStress = ifft(Stress_fft);
                dtaudt = -(M/2)*ElasticStress(N_pad + 1: N_pad + N);
            
            %%% Normal stress
                NormFull_fft = 1i*WaveNumber2.*Ve2_fft;
                dsigmadt = real(fftshift(ifft(ifftshift(NormFull_fft))));
                dsigmadt = -(M*Beta/2)*dsigmadt(N_pad + 1 : N_pad + N);
                % dsigmadt = -(M*Beta/2)*zeros(N,1);
            
            %%% Velocity equation.
                g = exp((mu_ref + b.*log(v_ref.*state./d_c))./a);
                u = (vel/(2*v_ref)).*g;
                f = asinh(u);
                dgdt = (b.*g./(a.*state)).*dstatedt;
                dvdt = (g + (2*v_ref*eta./(a.*sigma))...
                    .*(1 + u.^2).^(1/2)).^(-1).*((2*v_ref./(a.*sigma))...
                    .*(1 + u.^2).^(1/2).*(Loading + dtaudt - a.*f.*dsigmadt)...
                    - vel.*dgdt);
                
            %%% Combine evolution equations.
                dVarsdt = [dvdt; dtaudt; dstatedt; dsigmadt];
            end % FullSpaceFault

%%%----------------------------------------------------------------------------------------------%%%
%%%----------------------------------------------------------------------------------------------%%%
%%% Governing equations for a dipping fault.            
            function dVarsdt = DippingFault(t, Vars, X, Z, N)
                vel = Vars(1:N);
                % tau = Vars(N+1:2*N);
                state = Vars(2*N+1:3*N);
                sigma = Vars(3*N+1:4*N);
            
            %%% Shear stress equation.
                dtaudt = K_s*(vel - v_plate);
                % dtaudt = K_s*(vel - Loading);
            %%% Normal stress equation.
                dsigmadt = K_n*(vel - v_plate);
                % dsigmadt = K_n*(vel - Loading);
            %%% State evolution.
                switch(StateCase)
                    case 'Aging'
                        dstatedt = 1 - vel.*state./d_c;
                    case 'Slip'
                        dstatedt = -(vel.*state./d_c).*log(vel.*state./d_c);
                end            
            %%% Velocity equation.
                g = exp((mu_ref + b.*log(v_ref.*state./d_c))./a);
                u = (vel/(2*v_ref)).*g;
                f = asinh(u);
                dgdt = (b.*g./(a.*state)).*dstatedt;
                dvdt = (g + (2*v_ref*eta./(a.*sigma))...
                    .*(1 + u.^2).^(1/2)).^(-1).*((2*v_ref./(a.*sigma))...
                    .*(1 + u.^2).^(1/2).*(Loading + dtaudt - a.*f.*dsigmadt)...
                    - vel.*dgdt);
                
            %%% Combine evolution equations.
                dVarsdt = [dvdt; dtaudt; dstatedt; dsigmadt];
            end %DippingFault
            
%%%----------------------------------------------------------------------------------------------%%%
%%% ode events function. Stops the simulation when the "stop" button is pushed.
            function [position, isterminal, direction] = Stop(t, Vars, X, Z, N)                              
                position = app.STOP;
                isterminal = 1;
                direction = 0;                
            end %Stop

%%%----------------------------------------------------------------------------------------------%%%
%%% ode output function, plots live simulation data.
            function status = PlotVel_ode(time, Vars, flag, X, Z, N)
                  
                persistent v_line b_line %gs
                status = 0;
                
                switch(flag)
                    case 'init'
                    %%% Initialize the plotting figures.
                        if app.PlotsOnCheckBox.Value
                            hold(app.GeoAxes, 'on')
                            b_line = line(app.GeoAxes, X/1e3, nan(size(X)), 'Color', 'b',...
                                'LineWidth', 2);
                            hold(app.GeoAxes, 'off')

                            v_line = semilogy(app.VelAxes, X/1e3, Vars(1:N), 'r', 'LineWidth', 2);
                            v_line.YLimInclude = 'off';
                            v_line.XLimInclude = 'off';
                        end

                    case ''
                    %%% Display the in-simulation time. Do this without drawnow?
                        r = mod(time(1), spy);
                        years = (time(1) - r)/spy;
                        days = r/(24*3600);
                        app.TimeLabel.Text = sprintf('Time: %8d years, %6.2f days', [years, days]);                        
                        app.Label.Text = sprintf(': %6.2f', toc);
                        
                    %%% This is some code to slow down the drawnow command even more than the limitrate 
                    %%% option does. For no plots, seeing 11% decrease. For plots on, seeing 25% decrease.
                        % time2 = toc(timerval);
                        % if time2 > 0.1
                        %     drawnow;
                        %     timerval = tic;
                        % end
                        drawnow limitrate
                        
                    %%% Plot the logarithm of the slip velocity.
                        if app.PlotsOnCheckBox.Value                            
                            set(v_line, 'XData', X/1e3, 'YData', Vars(1:N));                            

                        %%% Plot blue fast speeds on GeoAxes. Code has problems when there are multiple blue
                        %%% patches that aren't contiguous. Need other cases.
                            switch(GeoCase)
                                case {'Thrust Fault', 'Normal Fault', 'Strike Slip'}
                                    i_blue = Vars(1:N) > 1e-2;
                                    set(b_line, 'XData', 1e-3*X(i_blue), 'YData', 1e-3*Z(i_blue))
                            end
%                             plot(app.GeoAxes, 1e-3*X(i_blue), zeros(sum(i_blue),1), 'b', 'LineWidth', 2)
                        end

                    case 'done'
                        hold(app.GeoAxes, 'off')                        
                end % switch
            end % PlotVel_ode

%%%----------------------------------------------------------------------------------------------%%%
%%% dat output function, plots live simulation data.
            function status = PlotVel_dat(time, Vars, flag, X, Z, N)
                  
                persistent v_line b_line gs
                status = 0;
                
                switch(flag)
                    case 'init'
                    %%% Initialize the plotting figures.
                        if app.PlotsOnCheckBox.Value
                            hold(app.GeoAxes, 'on')
                            b_line = line(app.GeoAxes, X/1e3, nan(size(X)), 'Color', 'b',...
                                'LineWidth', 2);
                            hold(app.GeoAxes, 'off')

                            v_line = semilogy(app.VelAxes, X/1e3, Vars(1:N), 'r', 'LineWidth', 2);
                            v_line.YLimInclude = 'off';
                            v_line.XLimInclude = 'off';
                        end
                        
                    %%% Initialize the .dat files.
                        Vn = 'Vel.dat';
                        Tn = 'Time.dat';
                        Tan = 'Tau.dat';
                        Sig_n = 'Sigma.dat';
                        Stn = 'State.dat';
                        gs.Vel = SaveStreamData('Init',Vn);
                        gs.Time = SaveStreamData('Init',Tn);
                        gs.Tau = SaveStreamData('Init',Tan);
                        gs.Sigma = SaveStreamData('Init',Sig_n);
                        gs.State = SaveStreamData('Init', Stn);
                        
                    %%% Write to the .dat files for the initial conditions.
                        SaveStreamData('Write', gs.Vel, Vars(1:N, 1));
                        SaveStreamData('Write', gs.Time, time(1));
                        SaveStreamData('Write', gs.State, Vars(2*N+1 : 3*N, 1));
                        SaveStreamData('Write', gs.Tau, Vars(N+1 : 2*N, 1));
                        SaveStreamData('Write', gs.Sigma, Vars(3*N+1 : 4*N, 1));
                
                        
                    case ''
                    %%% Display the in-simulation time. Do this without drawnow?
                        r = mod(time(1), spy);
                        years = (time(1) - r)/spy;
                        days = r/(24*3600);
                        app.TimeLabel.Text = sprintf('Time: %8d years, %6.2f days', [years, days]);                        
                        app.Label.Text = sprintf(': %6.2f', toc);
                        
                    %%% This is some code to slow down the drawnow command even more than the limitrate 
                    %%% option does. For no plots, seeing 11% decrease. For plots on, seeing 25% decrease.
                        % time2 = toc(timerval);
                        % if time2 > 0.1
                        %     drawnow;
                        %     timerval = tic;
                        % end
                        drawnow limitrate

                    %%% Plot the logarithm of the slip velocity.
                        if app.PlotsOnCheckBox.Value                            
                            set(v_line, 'XData', X/1e3, 'YData', Vars(1:N));                            

                        %%% Plot blue fast speeds on GeoAxes. Code has problems when there are multiple blue
                        %%% patches that aren't contiguous. Need other cases.
                            switch(GeoCase)
                                case {'Thrust Fault', 'Normal Fault', 'Strike Slip'}
                                    i_blue = Vars(1:N) > 1e-2;
                                    set(b_line, 'XData', 1e-3*X(i_blue), 'YData', 1e-3*Z(i_blue))
                            end
%                             plot(app.GeoAxes, 1e-3*X(i_blue), zeros(sum(i_blue),1), 'b', 'LineWidth', 2)
                        end                                       

                    %%% Write to the .dat files during simulation         
                        SaveStreamData('Write', gs.Vel, Vars(1:N, 1));
                        SaveStreamData('Write', gs.Time, time(1));
                        SaveStreamData('Write', gs.State, Vars(2*N+1 : 3*N, 1));
                        SaveStreamData('Write', gs.Tau, Vars(N+1 : 2*N, 1));
                        SaveStreamData('Write', gs.Sigma, Vars(3*N+1 : 4*N, 1));
                        
                    case 'done'
                        hold(app.GeoAxes, 'off')
                    %%% Finalize the .dat files.
                        SaveStreamData('Finalize', gs.Vel);
                        SaveStreamData('Finalize', gs.Time);
                        SaveStreamData('Finalize', gs.Tau);
                        SaveStreamData('Finalize', gs.Sigma);
                        SaveStreamData('Finalize', gs.State);                                               
                end % switch            
            end % PlotVel_dat
        end % RunFaultSimUI        
        
        function DisableControls(app)
        %%% Disable Start, Stop, and Restart buttons.
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.RestartButton.Enable = "off";
        end

        function UpdateGridInfo(app, GridFlag)
        %%% Update the grid spacing/scaling and number of points whenever
        %%% any of the other values are changed.
            FaultLength = 1e3*app.FaultLengthkmEditField.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            d_c = app.dcmEditField.Value;
            EffStress_0 = app.NormalStressMPaEditField.Value;            
            ShearMod = 1e3*app.ShearModulusGPaEditField.Value;
            Poisson = app.PoissonRatioEditField.Value;
            H_s = pi*ShearMod*d_c/(EffStress_0*(1 - Poisson)*(b-a));
            L_b = d_c*ShearMod/(b*EffStress_0*(1 - Poisson));
            app.hkmLabel.Text = sprintf('h* = %10.3f [km]', H_s/1e3);
            app.LbkmLabel.Text = sprintf('Lb = %10.3f [km]', L_b/1e3);

            switch(GridFlag)
                case 'Lbdxi'
                    Lbdxi = app.LbdxiEditField.Value;
                    app.dximEditField.Value = L_b/Lbdxi;
                    app.GridPointsEditField.Value = round(Lbdxi*FaultLength/L_b);
                case 'dxi'
                    dxi = app.dximEditField.Value;
                    app.LbdxiEditField.Value = L_b/dxi;
                    app.GridPointsEditField.Value = round(FaultLength/dxi);
                case 'N'
                    N = app.GridPointsEditField.Value;
                    app.dximEditField.Value = FaultLength/(N - 1);
                    app.LbdxiEditField.Value = (N-1)*L_b/FaultLength;
                otherwise
            end
            
        end
    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %%% Parameters structure containing all necessary information to run a
        %%% simulation.
            app.p = struct('Geometry', [], 'Material', [], 'Friction', [],...
                'Options', []);
        %%% Set the stop flag to 1, which means "don't stop".
            app.STOP = 1;
        %%% Populate the UI with values.
        %%% Geometry
            app.GeometryDropDown.Value = 'Thrust Fault';
            app.SurfaceSlopeEditField.Value = 0;
            app.DipAngleEditField.Value = 10;
            app.BurialDepthkmEditField.Value = 0;
            app.FaultLengthkmEditField.Value = 50;
        %%% Normal Stress
            app.NormalStressMPaEditField.Value = 5;
            app.PorePressureRatioEditField.Enable = "off";
        %%% Elastic
            app.ShearModulusGPaEditField.Value = 30;
            app.PoissonRatioEditField.Value = 0.25;
            app.vsmsEditField.Value = 3000;
        %%% Friction
            app.aEditField.Value = 0.007;
            app.bEditField.Value = 0.01;
            app.dcmEditField.Value = 0.01;
            app.v0EditField.Value = 1e-9;
            app.mu0EditField.Value = 0.3;
        %%% Grid.
            H_star = pi*30e3*0.01/(5*(1 - 0.25)*(0.01-0.007));
            L_b = 30e3*0.01/(0.01*5*(1 - 0.25));
            app.hkmLabel.Text = sprintf('h* = %10.3f [km]', H_star/1e3);
            app.LbkmLabel.Text = sprintf('Lb = %10.3f [km]', L_b/1e3);
            app.LbdxiEditField.Value = 40;
            app.dximEditField.Value = L_b/40;
            app.GridPointsEditField.Value = round(40*50e3/L_b);
            app.FourierSpaceCheckBox.Value = false;
            app.FourierSpaceCheckBox.Enable = 'off';
        %%% Simulation
            app.LoadingRatemsEditField.Value = 1e-9;
            app.RunTimeyrEditField.Value = 200;
        %%% Export data structure name.
            app.NameEditField.Value = 'SimData';
        %%% Disable Start, Stop, and Restart buttons.
            DisableControls(app);
        %%% Remove the axes toolbars and interactivity.
            app.VelAxes.Toolbar = [];
            app.GeoAxes.Toolbar = [];
            disableDefaultInteractivity(app.VelAxes)            
            disableDefaultInteractivity(app.GeoAxes)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            GetParameters(app); % This interferes with imported p.
            app.STOP = 1;
            app.p.Options.Restart = 0;
            tic;
            app.Out = RunFaultSimUI(app);
            %toc; % uncomment this to print execution time to cmd window.
        end

        % Button pushed function: SetPropertiesButton
        function SetPropertiesButtonPushed(app, event)
        %%% import the properties from the edit fields into the parameters
        %%% structure.
            DippingFaultSetUpUI(app);
        %%% Display the number of grid points, and RSF length scales.
            app.GridPointsEditField.Value = app.p.Geometry.GridPoints;
            app.hkmLabel.Text = sprintf('h* = %10.3f [km]', app.p.Friction.H_star/1e3);
            app.LbkmLabel.Text = sprintf('Lb = %10.3f [km]', app.p.Friction.L_b/1e3);
        %%% Disable SetProperties button.
            app.SetPropertiesButton.Enable = "off";
        %%% Enable Start, Stop, and Restart buttons.
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.RestartButton.Enable = "on";            
        end

        % Button pushed function: PlotGeometryButton
        function PlotGeometryButtonPushed(app, event)
        %%% Maintain proper alignment for VelAxes and GeoAxes. This should
        %%% only need to be called once, so should properly be in the
        %%% startupFcn, but for some reason doesn't do anything there.
            app.VelAxes.PositionConstraint = 'innerposition';
            app.GeoAxes.PositionConstraint = 'innerposition';
            
            switch(app.GeometryDropDown.Value)
                case 'Full Space'
                    FaultLength = app.FaultLengthkmEditField.Value;
                    x_max = FaultLength/2;
                    x_min = -FaultLength/2; 
                    area(app.GeoAxes, [x_min,x_max], [x_max,x_max], 'FaceColor', '#BEBEBE')
                    hold(app.GeoAxes, 'on');
                    area(app.GeoAxes, [x_min,x_max], [x_min,x_min], 'FaceColor', '#BEBEBE')
                    plot(app.GeoAxes, [x_min; x_max], [0; 0], 'r', 'LineWidth', 2)
                    xlim(app.GeoAxes, [x_min x_max])
                    ylim(app.GeoAxes, [x_min x_max])
                    hold(app.GeoAxes, 'off')
                %%% Set VelAxes limits to speed up drawnow calls.
                    set(app.VelAxes, 'YScale', 'log')
                    xlim(app.VelAxes, [x_min x_max])
                    ylim(app.VelAxes, [1e-15 1e0])
                    
                case {'Thrust Fault', 'Normal Fault', 'Strike Slip'}
                    alpha = app.SurfaceSlopeEditField.Value;
                    beta = app.DipAngleEditField.Value;
                    FaultLength = app.FaultLengthkmEditField.Value;
                    z_burial = app.BurialDepthkmEditField.Value;
                    z_fault = z_burial + FaultLength*sin(pi*beta/180);
                    z_upper = -FaultLength*cos(pi*beta/180)*tan(pi*alpha/180);
                %%% Abosolute value for strike-slip faults with beta > 90.
                %%% This may cause problems for dip-slip faults.
                    x_max = FaultLength*abs(cos(pi*beta/180));
                
                    if alpha == 0 && beta ~= 0
                        area(app.GeoAxes, [0, x_max], [0, z_upper], z_fault, 'FaceColor', '#BEBEBE')
                        ylim(app.GeoAxes, [-0.1*z_fault z_fault])

                    elseif z_burial ~= 0 && beta == 0
                        area(app.GeoAxes, [0, x_max], [0, z_upper], 2*z_fault, 'FaceColor', '#BEBEBE')
                        ylim(app.GeoAxes, [z_upper-0.1*z_fault 2*z_fault])

                    else
                        area(app.GeoAxes, [0, x_max], [0, z_upper], z_fault, 'FaceColor', '#BEBEBE')
                        ylim(app.GeoAxes, [z_upper z_fault])
                    end
                    hold(app.GeoAxes, 'on');

                %%% Plot the upper surface.
                    plot(app.GeoAxes, [0; x_max], [0; z_upper], 'k', 'LineWidth', 2)
                    
                %%% Plot the fault surface
                    plot(app.GeoAxes, [0; x_max], [z_burial; z_fault], 'r', 'LineWidth', 2)
                    xlim(app.GeoAxes, [0 x_max])

                    set(app.GeoAxes, 'YDir', 'reverse'); % can maybe just call once during initialization?
                    hold(app.GeoAxes, 'off')

                %%% Set VelAxes limits to speed up drawnow calls.
                    set(app.VelAxes, 'YScale', 'log')
                    xlim(app.VelAxes, [0 x_max])
                    ylim(app.VelAxes, [1e-15 1e0])
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.STOP = 0;            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            SimData = struct('Params', app.p, 'Output', app.Out);
            DataName = app.NameEditField.Value;
            assignin('base', DataName, SimData);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.STOP = 1;
            app.p.Options.Restart = 1;
            RunTime = app.RunTimeyrEditField.Value;
            time = [0 RunTime];
            app.p.Geometry.Runtime = time;
            tic;
        %%% app.Out should already exist, since this is a restart. So
        %%% Stitch the restart Out onto the current app.Out
            Out_R = RunFaultSimUI(app);
            app.Out.NormalStress = [app.Out.NormalStress; Out_R.NormalStress(2:end,:)];
            app.Out.ShearStress = [app.Out.ShearStress; Out_R.ShearStress(2:end,:)];
            app.Out.State = [app.Out.State; Out_R.State(2:end,:)];
            app.Out.Vel = [app.Out.Vel; Out_R.Vel(2:end,:)];
            app.Out.Time = [app.Out.Time; Out_R.Time(2:end,:) + app.Out.Time(end)];
            app.Out.Slip = [app.Out.Slip; Out_R.Slip(2:end,:) + app.Out.Slip(end,:)];
            app.Out.Final = Out_R.Final;
        end

        % Value changed function: dcmEditField
        function dcmEditFieldValueChanged(app, event)
            % value = app.dcmEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            % value = app.bEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: ShearModulusGPaEditField
        function ShearModulusGPaEditFieldValueChanged(app, event)
            % value = app.ShearModulusGPaEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: PoissonRatioEditField
        function PoissonRatioEditFieldValueChanged(app, event)
            % value = app.PoissonRatioEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: NormalStressMPaEditField
        function NormalStressMPaEditFieldValueChanged(app, event)
            % value = app.NormalStressMPaEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: FaultLengthkmEditField
        function FaultLengthkmEditFieldValueChanged(app, event)
            % value = app.FaultLengthkmEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'dxi');
        end

        % Value changed function: SurfaceSlopeEditField
        function SurfaceSlopeEditFieldValueChanged(app, event)
            % value = app.SurfaceSlopeEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: DipAngleEditField
        function DipAngleEditFieldValueChanged(app, event)
            % value = app.DipAngleEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: BurialDepthkmEditField
        function BurialDepthkmEditFieldValueChanged(app, event)
            % value = app.BurialDepthkmEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Value changed function: GeometryDropDown
        function GeometryDropDownValueChanged(app, event)
            value = app.GeometryDropDown.Value;
            switch(value)
                case 'Full Space'
                    app.FourierSpaceCheckBox.Enable = "on";
                    app.SetPropertiesButton.Enable = "on";
                    DisableControls(app);
                    app.BurialDepthkmEditField.Enable = "off";
                    app.DipAngleEditField.Enable = "off";
                    app.SurfaceSlopeEditField.Enable = "off";
                    app.PoissonRatioEditField.Enable = "on";

                case {'Thrust Fault', 'Normal Fault'}
                    if strcmp(event.PreviousValue, 'Full Space') ||...
                            strcmp(event.PreviousValue, 'Strike Slip')
                        app.FourierSpaceCheckBox.Enable = "off";
                        app.SetPropertiesButton.Enable = "on";
                        DisableControls(app);
                        app.BurialDepthkmEditField.Enable = "on";
                        app.DipAngleEditField.Enable = "on";
                        app.SurfaceSlopeEditField.Enable = "on";
                        app.PoissonRatioEditField.Enable = "on";
                    end

                case 'Strike Slip'
                    app.FourierSpaceCheckBox.Enable = "off";
                    app.SetPropertiesButton.Enable = "on";
                    DisableControls(app);
                    app.BurialDepthkmEditField.Value = 0;
                    app.BurialDepthkmEditField.Enable = "off";
                    app.DipAngleEditField.Enable = "on";
                    app.SurfaceSlopeEditField.Value = 0;
                    app.SurfaceSlopeEditField.Enable = "off";
                    app.PoissonRatioEditField.Value = 0;
                    app.PoissonRatioEditField.Enable = "off";
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
        %%% Import a parameter structure from the workspace.

        %%% Create a structure array using the name that is entered in the
        %%% import field.
            p_import = evalin('base', app.ImportEditField.Value);
            app.p = p_import;
            
        %%% Display the properties from the imported parameter structure.
        %%% Geometry Panel.
            app.FaultLengthkmEditField.Value = app.p.Geometry.FaultLength/1e3;
            app.GeometryDropDown.Value = app.p.Options.Geometry;
            switch(app.p.Options.Geometry)

                case 'Full Space'
                    app.FourierSpaceCheckBox.Enable = "on";
                    app.BurialDepthkmEditField.Enable = "off";
                    app.DipAngleEditField.Enable = "off";
                    app.SurfaceSlopeEditField.Enable = "off";
                    app.PoissonRatioEditField.Enable = "on";

                    app.FourierSpaceCheckBox.Value = app.p.Options.FourierSpace;

                case {'Thrust Fault', 'Normal Fault'}
                    app.FourierSpaceCheckBox.Enable = "off";
                    app.BurialDepthkmEditField.Enable = "on";
                    app.DipAngleEditField.Enable = "on";
                    app.SurfaceSlopeEditField.Enable = "on";
                    app.PoissonRatioEditField.Enable = "on";

                    app.DipAngleEditField.Value = app.p.Geometry.FaultDip;
                    app.SurfaceSlopeEditField.Value = app.p.Geometry.SurfaceSlope;
                    app.BurialDepthkmEditField.Value = app.p.Geometry.BurialDepth/1e3;

                case 'Strike Slip'
                    app.FourierSpaceCheckBox.Enable = "off";
                    app.BurialDepthkmEditField.Enable = "off";
                    app.DipAngleEditField.Enable = "on";
                    app.SurfaceSlopeEditField.Enable = "off";
                    app.PoissonRatioEditField.Enable = "off";

                    app.DipAngleEditField.Value = app.p.Geometry.FaultDip;
                    app.SurfaceSlopeEditField.Value = 0;
                    app.BurialDepthkmEditField.Value = 0;
            end

        %%% Plot the geometry.
            PlotGeometryButtonPushed(app, []);

        %%% Elastic Parameters Panel.
        %%% If effective stress is variable, use and display the maximum value.
            app.NormalStressMPaEditField.Value = max(app.p.Material.BackgroundStress);
            if strcmp(app.p.Options.Geometry, 'Strike Slip') == 1
                app.PoissonRatioEditField.Value = 0;
            else
                app.PoissonRatioEditField.Value = app.p.Material.PoissonRatio;
            end
            app.ShearModulusGPaEditField.Value = app.p.Material.ShearModulus/1e3;
            app.vsmsEditField.Value = app.p.Material.ShearWaveSpeed;

        %%% Friction Parameters Panel.
            app.mu0EditField.Value = app.p.Friction.mu_ref;
            app.v0EditField.Value = app.p.Friction.v_ref;
            app.dcmEditField.Value = mean(app.p.Friction.d_c);
            app.bEditField.Value = mean(app.p.Friction.b);
        %%% For variable RSF parameters, display the most unstable values.
            app.aEditField.Value = min(app.p.Friction.a);

        %%% Grid Controls Panel.
            app.GridPointsEditField.Value = app.p.Geometry.GridPoints;
            app.dximEditField.Value = app.p.Geometry.GridSpacing;
        %%% If L_b varies, display and use the smallest value.
            app.LbdxiEditField.Value = min(app.p.Friction.L_b)/app.p.Geometry.GridSpacing;
            app.LbkmLabel.Text = sprintf('Lb = %10.3f [km]',...
                min(app.p.Friction.L_b)/1e3);
            app.hkmLabel.Text = sprintf('h* = %10.3f [km]',...
                app.p.Friction.H_star/1e3);
            app.LoadingRatemsEditField.Value = app.p.Material.PlateRate;

        %%% Simulation Controls Panel.
            app.StateLawDropDown.Value = app.p.Options.StateLaw;
            app.RunTimeyrEditField.Value = app.p.Geometry.Runtime(1,2);            
        
        %%% Disable SetProperties button.
            app.SetPropertiesButton.Enable = "off";

        %%% Enable Start, Stop, and Restart buttons.
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.RestartButton.Enable = "on";
            
        end

        % Value changed function: LbdxiEditField
        function LbdxiEditFieldValueChanged(app, event)
            % value = app.LbdxiEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');
        end

        % Value changed function: dximEditField
        function dximEditFieldValueChanged(app, event)
            % value = app.dximEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'dxi');
        end

        % Value changed function: GridPointsEditField
        function GridPointsEditFieldValueChanged(app, event)
            % value = app.GridPointsEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'N');
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            % value = app.aEditField.Value;
            UpdateGridInfo(app, '');
        end

        % Selection changed function: EffectiveNormalStressButtonGroup
        function EffectiveNormalStressButtonGroupSelectionChanged(app, event)
            selectedButton = app.EffectiveNormalStressButtonGroup.SelectedObject;
            if iscell(selectedButton.Text) == 1 % Pore pressure ratio
                app.NormalStressMPaEditField.Enable = "off";
                app.PorePressureRatioEditField.Enable = "on";
            else % Constant
                app.NormalStressMPaEditField.Enable = "on";
                app.PorePressureRatioEditField.Enable = "off";
            end
        end

        % Value changed function: PorePressureRatioEditField
        function PorePressureRatioEditFieldValueChanged(app, event)
            % value = app.PorePressureRatioEditField.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
            UpdateGridInfo(app, 'Lbdxi');            
        end

        % Value changed function: FourierSpaceCheckBox
        function FourierSpaceCheckBoxValueChanged(app, event)
            value = app.FourierSpaceCheckBox.Value;
            app.SetPropertiesButton.Enable = "on";
            DisableControls(app);
        end

        % Callback function
        function vsmsEditFieldValueChanged(app, event)
            value = app.vsmsEditField.Value;
            % UpdateGridInfo(app, '');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RSFaultZUIFigure = uifigure('Visible', 'off');
            app.RSFaultZUIFigure.Name = 'RSFaultZ';
            app.GeoAxes = uiaxes(app.RSFaultZUIFigure);
            xlabel(app.GeoAxes, 'Distance (km)')
            ylabel(app.GeoAxes, 'Depth (km)')
            app.VelAxes = uiaxes(app.RSFaultZUIFigure);
            xlabel(app.VelAxes, 'Distance (km)')
            ylabel(app.VelAxes, 'Slip Speed (m / s)')
            app.GeometryPanel = uipanel(app.RSFaultZUIFigure);
            app.GeometryPanel.Title = 'Geometry';
            app.SurfaceSlopeEditFieldLabel = uilabel(app.GeometryPanel);
            app.SurfaceSlopeEditFieldLabel.Text = 'Surface Slope';
            app.SurfaceSlopeEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SurfaceSlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceSlopeEditFieldValueChanged, true);
            app.DipAngleEditFieldLabel = uilabel(app.GeometryPanel);
            app.DipAngleEditFieldLabel.Text = 'Dip Angle';
            app.DipAngleEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.DipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @DipAngleEditFieldValueChanged, true);
            app.FaultLengthkmEditFieldLabel = uilabel(app.GeometryPanel);
            app.FaultLengthkmEditFieldLabel.Text = 'Fault Length [km]';
            app.FaultLengthkmEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.FaultLengthkmEditField.ValueChangedFcn = createCallbackFcn(app, @FaultLengthkmEditFieldValueChanged, true);
            app.BurialDepthkmEditFieldLabel = uilabel(app.GeometryPanel);
            app.BurialDepthkmEditFieldLabel.Text = 'Burial Depth [km]';
            app.BurialDepthkmEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.BurialDepthkmEditField.ValueChangedFcn = createCallbackFcn(app, @BurialDepthkmEditFieldValueChanged, true);
            app.PlotGeometryButton = uibutton(app.GeometryPanel, 'push');
            app.PlotGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGeometryButtonPushed, true);
            app.PlotGeometryButton.Text = 'Plot Geometry';
            app.GeometryDropDownLabel = uilabel(app.GeometryPanel);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.GeometryPanel);
            app.GeometryDropDown.Items = {'Full Space', 'Normal Fault', 'Thrust Fault', 'Strike Slip'};
            app.GeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryDropDownValueChanged, true);
            app.GeometryDropDown.Value = 'Thrust Fault';
            app.ElasticParametersPanel = uipanel(app.RSFaultZUIFigure);
            app.ElasticParametersPanel.Title = 'Elastic Parameters';
            app.ShearModulusGPaEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.ShearModulusGPaEditFieldLabel.Text = 'Shear Modulus [GPa]';
            app.ShearModulusGPaEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.ShearModulusGPaEditField.ValueChangedFcn = createCallbackFcn(app, @ShearModulusGPaEditFieldValueChanged, true);
            app.PoissonRatioEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.PoissonRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonRatioEditFieldValueChanged, true);
            app.SWaveSpeedmsEditFieldLabel = uilabel(app.ElasticParametersPanel);
            app.SWaveSpeedmsEditFieldLabel.Text = 'S-Wave Speed [m/s]';
            app.vsmsEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.FrictionParametersPanel = uipanel(app.RSFaultZUIFigure);
            app.FrictionParametersPanel.Title = 'Friction Parameters';
            app.aEditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.dcmLabel = uilabel(app.FrictionParametersPanel);
            app.dcmLabel.Text = 'dc [m]';
            app.dcmEditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.dcmEditField.ValueChangedFcn = createCallbackFcn(app, @dcmEditFieldValueChanged, true);
            app.v0msLabel = uilabel(app.FrictionParametersPanel);
            app.v0msLabel.Text = 'v0 [m/s]';
            app.v0EditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.mu0EditFieldLabel = uilabel(app.FrictionParametersPanel);
            app.mu0EditFieldLabel.Text = 'mu0';
            app.mu0EditField = uieditfield(app.FrictionParametersPanel, 'numeric');
            app.GridControlsPanel = uipanel(app.RSFaultZUIFigure);
            app.GridControlsPanel.Title = 'Grid Controls';
            app.SetPropertiesButton = uibutton(app.GridControlsPanel, 'push');
            app.SetPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @SetPropertiesButtonPushed, true);
            app.SetPropertiesButton.Text = 'Set Properties';
            app.GridPointsEditFieldLabel = uilabel(app.GridControlsPanel);
            app.GridPointsEditFieldLabel.Text = 'Grid Points';
            app.GridPointsEditField = uieditfield(app.GridControlsPanel, 'numeric');
            app.GridPointsEditField.ValueDisplayFormat = '%.0f';
            app.GridPointsEditField.ValueChangedFcn = createCallbackFcn(app, @GridPointsEditFieldValueChanged, true);
            app.hkmLabel = uilabel(app.GridControlsPanel);
            app.hkmLabel.Text = 'h* =               [km]';
            app.LbkmLabel = uilabel(app.GridControlsPanel);
            app.LbkmLabel.Text = 'Lb =               [km]';
            app.dximEditFieldLabel = uilabel(app.GridControlsPanel);
            app.dximEditFieldLabel.Text = 'dxi [m]';
            app.dximEditField = uieditfield(app.GridControlsPanel, 'numeric');
            app.dximEditField.ValueChangedFcn = createCallbackFcn(app, @dximEditFieldValueChanged, true);
            app.LbdxiEditFieldLabel = uilabel(app.GridControlsPanel);
            app.LbdxiEditFieldLabel.Text = 'Lb / dxi';
            app.LbdxiEditField = uieditfield(app.GridControlsPanel, 'numeric');
            app.LbdxiEditField.ValueChangedFcn = createCallbackFcn(app, @LbdxiEditFieldValueChanged, true);
            app.LoadingRatemsEditFieldLabel = uilabel(app.GridControlsPanel);
            app.LoadingRatemsEditFieldLabel.Text = {'Loading'; 'Rate [m/s]'};
            app.LoadingRatemsEditField = uieditfield(app.GridControlsPanel, 'numeric');
            app.ImportButton = uibutton(app.GridControlsPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ImportEditField = uieditfield(app.GridControlsPanel, 'text');
            app.FourierSpaceCheckBox = uicheckbox(app.GridControlsPanel);
            app.FourierSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @FourierSpaceCheckBoxValueChanged, true);
            app.FourierSpaceCheckBox.Text = 'Fourier Space';
            app.SimulationControlsPanel = uipanel(app.RSFaultZUIFigure);
            app.SimulationControlsPanel.Title = 'Simulation Controls';
            app.StartButton = uibutton(app.SimulationControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SimulationControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExportButton = uibutton(app.SimulationControlsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.NameEditFieldLabel = uilabel(app.SimulationControlsPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.SimulationControlsPanel, 'text');
            app.RestartButton = uibutton(app.SimulationControlsPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.StateLawDropDownLabel = uilabel(app.SimulationControlsPanel);
            app.StateLawDropDownLabel.Text = 'State Law';
            app.StateLawDropDown = uidropdown(app.SimulationControlsPanel);
            app.StateLawDropDown.Items = {'Aging', 'Slip', 'Other'};
            app.StateLawDropDown.Value = 'Slip';
            app.PlotsOnCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.PlotsOnCheckBox.Text = 'Plots On';
            app.PlotsOnCheckBox.Value = true;
            app.RunTimeyrEditFieldLabel = uilabel(app.SimulationControlsPanel);
            app.RunTimeyrEditFieldLabel.Text = 'Run Time [yr]';
            app.RunTimeyrEditField = uieditfield(app.SimulationControlsPanel, 'numeric');
            app.TimeLabel = uilabel(app.RSFaultZUIFigure);
            app.TimeLabel.Text = 'Time:               ';
            app.ExecutionTimesLabel = uilabel(app.RSFaultZUIFigure);
            app.ExecutionTimesLabel.Text = {'Execution'; 'Time [s]'};
            app.Label = uilabel(app.RSFaultZUIFigure);
            app.Label.Text = ':';
            app.EffectiveNormalStressButtonGroup = uibuttongroup(app.RSFaultZUIFigure);
            app.EffectiveNormalStressButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EffectiveNormalStressButtonGroupSelectionChanged, true);
            app.EffectiveNormalStressButtonGroup.Title = 'Effective Normal Stress';
            app.ConstantMPaButton = uiradiobutton(app.EffectiveNormalStressButtonGroup);
            app.ConstantMPaButton.Text = 'Constant [MPa]';
            app.ConstantMPaButton.Value = true;
            app.PorePressureRatioButton = uiradiobutton(app.EffectiveNormalStressButtonGroup);
            app.PorePressureRatioButton.Text = {'Pore Pressure'; 'Ratio'};
            app.NormalStressMPaEditField = uieditfield(app.EffectiveNormalStressButtonGroup, 'numeric');
            app.NormalStressMPaEditField.ValueChangedFcn = createCallbackFcn(app, @NormalStressMPaEditFieldValueChanged, true);
            app.PorePressureRatioEditField = uieditfield(app.EffectiveNormalStressButtonGroup, 'numeric');
            app.PorePressureRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PorePressureRatioEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.RSFaultZUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSFaultZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RSFaultZUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RSFaultZUIFigure)
        end
    end
end